classdef study < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        V10Label                  matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        AuthorYuanchengZhaiLabel  matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        source_text               matlab.ui.control.EditField
        obj_text                  matlab.ui.control.EditField
        data_num                  matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        down                      matlab.ui.control.Button
        up                        matlab.ui.control.Button
        botton_source             matlab.ui.control.Button
        botton_obj                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: botton_obj
        function botton_objPushed(app, event)

            [wenjianming1,lujing1]=uigetfile('*');
            global filename;

            filename=string([lujing1 wenjianming1]);

            app.obj_text.Value=filename;

%%%%%%%%%%%

        end

        % Button pushed function: botton_source
        function botton_sourcePushed(app, event)


            [wenjianming2,lujing2]=uigetfile('*');

            global source;

            source=string([lujing2 wenjianming2]);


            app.source_text.Value=source;


        end

        % Button pushed function: up
        function upPushed(app, event)
            msgbox 请等待。。。。。。。。。。
            
            
            global source;
            global filename;


            final=app.data_num.Value;


            rangesourceup='A2:A134';
            [~,sourceup]=xlsread(source,rangesourceup);
            [~,btsname,~]=xlsread(filename,'C:C');
            [num0]=xlsread(filename,'A:A');
            [~,date,~]=xlsread(filename,'B:B');
            [ta]=xlsread(filename,'D:D');
            [count]=xlsread(filename,'E:E');

            num=up(final,btsname,sourceup);


            %             xlswrite("solute.xls",num,"sheet1",'F2');
            %             xlswrite("solute.xls",date,"sheet1",'B2');
            %             xlswrite("solute.xls",btsname,"sheet1",'C2');
            A=["NO."];
            B=["Date"];
            C=["Bts-Bts"];
            D=["TA"];
            E=["Count"];
            F=["Line"];
            G=[A B C D E F];
            xlswrite("solute.xls",G,"Sheet1","A1:F1");


            %             xlswrite("solute.xls",A,"Sheet1","A1:A1");
            %             xlswrite("solute.xls",B,"Sheet1","B1:B1");
            %             xlswrite("solute.xls",C,"Sheet1","C1:C1");
            %             xlswrite("solute.xls",D,"Sheet1","D1:D1");
            %             xlswrite("solute.xls",E,"Sheet1","E1:E1");
            %             xlswrite("solute.xls",F,"Sheet1","F1:F1");


            %             xlswrite("solute.xls","序号","Sheet1","A1:A1");
            %             xlswrite("solute.xls","日期","Sheet1","B1:B1");
            %             xlswrite("solute.xls","切换点","Sheet1","C1:C1");
            %             xlswrite("solute.xls","TA","Sheet1","D1:D1");
            %             xlswrite("solute.xls","次数","Sheet1","E1:E1");
            %             xlswrite("solute.xls","线路排序编号","Sheet1","F1:F1");


            xlswrite("solute.xls",num0,"Sheet1","A2");
            xlswrite("solute.xls",date,"Sheet1","B2");
            xlswrite("solute.xls",btsname,"Sheet1","C2");
            xlswrite("solute.xls",ta,"Sheet1","D2");
            xlswrite("solute.xls",count,"Sheet1","E2");
            xlswrite("solute.xls",num,"Sheet1","F2");


            msgbox 导出成功！

            function num=up(final,btsname,sourceup)

                for i=1:final

                    for j=1:133


                        if strcmp(btsname(i,1),sourceup(j,1))==1
                            num(i,1)=j;
                            break
                        else
                            num(i,1)="切换点不存在";
                        end
                    end
                end


            end

        end

        % Button pushed function: down
        function downPushed(app, event)
            msgbox 请等待。。。。。。。。。。
            
            global source;
            global filename;


            final=app.data_num.Value;


            rangesourcedown='A2:A134';
            [~,sourceup]=xlsread(source,rangesourcedown);
            [~,btsname,~]=xlsread(filename,'C:C');
            [num0]=xlsread(filename,'A:A');
            [~,date,~]=xlsread(filename,'B:B');
            [ta]=xlsread(filename,'D:D');
            [count]=xlsread(filename,'E:E');

            num=cac(final,btsname,sourceup);


            %             xlswrite("solute.xls",num,"sheet1",'F2');
            %             xlswrite("solute.xls",date,"sheet1",'B2');
            %             xlswrite("solute.xls",btsname,"sheet1",'C2');
            A=["NO."];
            B=["Date"];
            C=["Bts-Bts"];
            D=["TA"];
            E=["Count"];
            F=["Line"];
            G=[A B C D E F];
            xlswrite("solute.xls",G,"Sheet1","A1:F1");


            %             xlswrite("solute.xls",A,"Sheet1","A1:A1");
            %             xlswrite("solute.xls",B,"Sheet1","B1:B1");
            %             xlswrite("solute.xls",C,"Sheet1","C1:C1");
            %             xlswrite("solute.xls",D,"Sheet1","D1:D1");
            %             xlswrite("solute.xls",E,"Sheet1","E1:E1");
            %             xlswrite("solute.xls",F,"Sheet1","F1:F1");


            %             xlswrite("solute.xls","序号","Sheet1","A1:A1");
            %             xlswrite("solute.xls","日期","Sheet1","B1:B1");
            %             xlswrite("solute.xls","切换点","Sheet1","C1:C1");
            %             xlswrite("solute.xls","TA","Sheet1","D1:D1");
            %             xlswrite("solute.xls","次数","Sheet1","E1:E1");
            %             xlswrite("solute.xls","线路排序编号","Sheet1","F1:F1");


            xlswrite("solute.xls",num0,"Sheet1","A2");
            xlswrite("solute.xls",date,"Sheet1","B2");
            xlswrite("solute.xls",btsname,"Sheet1","C2");
            xlswrite("solute.xls",ta,"Sheet1","D2");
            xlswrite("solute.xls",count,"Sheet1","E2");
            xlswrite("solute.xls",num,"Sheet1","F2");


            msgbox 导出成功！

            function num=cac(final,btsname,sourcedown)

                for i=1:final

                    for j=1:133

                        if strcmp(btsname(i,1),sourcedown(j,1))==1
                            num(i,1)=j;
                            break
                        else
                            num(i,1)="切换点不存在";
                        end
                    end
                end


            end

        end

        % Value changed function: data_num
        function data_numValueChanged(app, event)
            %             value = app.data_num.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.botton_obj = uibutton(app.UIFigure, 'push');
            app.botton_obj.ButtonPushedFcn = createCallbackFcn(app, @botton_objPushed, true);
            app.botton_obj.Text = '读取目标';
            app.botton_source = uibutton(app.UIFigure, 'push');
            app.botton_source.ButtonPushedFcn = createCallbackFcn(app, @botton_sourcePushed, true);
            app.botton_source.Text = '读取源数据';
            app.up = uibutton(app.UIFigure, 'push');
            app.up.ButtonPushedFcn = createCallbackFcn(app, @upPushed, true);
            app.up.Text = '上行';
            app.down = uibutton(app.UIFigure, 'push');
            app.down.ButtonPushedFcn = createCallbackFcn(app, @downPushed, true);
            app.down.Text = '下行';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数据量（行）';
            app.data_num = uieditfield(app.UIFigure, 'numeric');
            app.data_num.Limits = [0 Inf];
            app.data_num.ValueDisplayFormat = '%.0f';
            app.data_num.ValueChangedFcn = createCallbackFcn(app, @data_numValueChanged, true);
            app.obj_text = uieditfield(app.UIFigure, 'text');
            app.source_text = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '基站切换点自动排序工具';
            app.AuthorYuanchengZhaiLabel = uilabel(app.UIFigure);
            app.AuthorYuanchengZhaiLabel.Text = 'Author：Yuancheng Zhai';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '说明：导入数据前请先删除原表格的表头，否则会出现乱序。';
            app.V10Label = uilabel(app.UIFigure);
            app.V10Label.Text = 'V1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = study

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end